classdef FlexBenchApp_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ConnectLamp    matlab.ui.control.Lamp
        ConnectButton  matlab.ui.control.Button
        PortDropDown   matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        ESP32
        BaudRate = 115200;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %{
            puertos = serialportlist("available");
            app.PortDropDown.Items = puertos;
            
            % Si encuentra algún puerto, auto-selecciona el primero
            if ~isempty(puertos)
                app.PortDropDown.Value = puertos(1);
            else
                app.PortDropDown.Items = {'No detectado'};
            end
           %}
        end

        % Drop down opening function: PortDropDown
        function PortDropDownOpening(app, event)
            app.PortDropDown.Items = serialportlist();

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
                try
                    app.ConnectLamp.Color = 'yellow';
                    app.ConnectButton.Text = 'Connecting...';
                    drawnow;


                    % Leemos lo que el usuario ha seleccionado en los desplegables
                    app.ESP32 = serialport(app.PortDropDown.Value, app.BaudRate);
                    configureTerminator(app.ESP32, "CR/LF");
                    
                    flush(app.ESP32);

                    % Cada vez que aparezca un terminator ejecuta leerMensajeSerie"
                    configureCallback(app.ESP32, "terminator", @app.leerMensajeSerie);
                    
                    % Cambiamos la luz a verde y el texto del botón
                    app.ConnectLamp.Color = 'green';
                    app.ConnectButton.Text = 'Connected';
                    app.ConnectButton.Enable = 'off'; % Desactivamos el botón para no conectar 2 veces
        
                    % Creamos un temporizador que se ejecuta cada 0.5 segundos
                    % Su única función es enviar la letra 'R' al ESP32
                    %app.TimerDatos = timer('ExecutionMode', 'fixedRate', 'Period', 0.5, 'TimerFcn', @(~,~) writeline(app.ESP32, "R"));
                    %start(app.TimerDatos);
                    
                    
                catch ME
                    % Si algo falla (ej. el puerto está en uso), sacamos una alerta
                    uialert(app.UIFigure, "Error: " + ME.message, "Error in connection");
                    app.ConnectLamp.Color = 'red';
                    app.ConnectButton.Text = 'Error in connection';
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {''};
            app.PortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortDropDownOpening, true);
            app.PortDropDown.Value = '';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlexBenchApp_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end