classdef SpecimenParamsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InfillDropDown                  matlab.ui.control.DropDown
        InfillLabel                     matlab.ui.control.Label
        LengthcmDropDown                matlab.ui.control.DropDown
        LengthcmDropDownLabel           matlab.ui.control.Label
        ThicknesscmEditField            matlab.ui.control.NumericEditField
        ThicknesscmEditFieldLabel       matlab.ui.control.Label
        WidthcmEditField                matlab.ui.control.NumericEditField
        WidthcmEditFieldLabel           matlab.ui.control.Label
        ConsecutiveTestsEditField       matlab.ui.control.NumericEditField
        ConsecutiveTestsEditFieldLabel  matlab.ui.control.Label
        SpecimenNameEditField           matlab.ui.control.EditField
        SpecimenNameEditFieldLabel      matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        AcceptButton                    matlab.ui.control.Button
        SpecimenParamsDialogLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        SpecimenName string = ""
        TotalTests double = 1
        Length_cm double = 10
        Width_cm double = 1
        Thickness_cm double = 0.2
        Infill double = 10 
        IsCanceled logical = true % Flag de seguridad inicializado en true
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)

            % 1. Guardamos el texto directo
            app.SpecimenName = app.SpecimenNameEditField.Value;
            
            % 2. Guardamos los números directos
            app.TotalTests = app.ConsecutiveTestsEditField.Value;
            app.Width_cm = app.WidthcmEditField.Value;
            app.Thickness_cm = app.ThicknesscmEditField.Value;
            
            % 3. Convertimos el texto de los DropDown a números
            app.Length_cm = str2double(app.LengthcmDropDown.Value);
            app.Infill = str2double(app.InfillDropDown.Value);
            
            % 4. Marcamos que NO se ha cancelado, ocultamos la ventana y reanudamos
            app.IsCanceled = false;
            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure);

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.IsCanceled = true;
            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.IsCanceled = true;
            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Test Parameters Input';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SpecimenParamsDialogLabel = uilabel(app.UIFigure);
            app.SpecimenParamsDialogLabel.Text = 'SpecimenParamsDialog';
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SpecimenNameEditFieldLabel = uilabel(app.UIFigure);
            app.SpecimenNameEditFieldLabel.Text = 'Specimen Name:';
            app.SpecimenNameEditField = uieditfield(app.UIFigure, 'text');
            app.ConsecutiveTestsEditFieldLabel = uilabel(app.UIFigure);
            app.ConsecutiveTestsEditFieldLabel.Text = 'Consecutive Tests:';
            app.ConsecutiveTestsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthcmEditFieldLabel = uilabel(app.UIFigure);
            app.WidthcmEditFieldLabel.Text = 'Width (cm):';
            app.WidthcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthcmEditField.Value = 1;
            app.ThicknesscmEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknesscmEditFieldLabel.Text = 'Thickness (cm):';
            app.ThicknesscmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThicknesscmEditField.Value = 0.2;
            app.LengthcmDropDownLabel = uilabel(app.UIFigure);
            app.LengthcmDropDownLabel.Text = 'Length (cm):';
            app.LengthcmDropDown = uidropdown(app.UIFigure);
            app.LengthcmDropDown.Items = {'5', '10', '15'};
            app.LengthcmDropDown.Value = '10';
            app.InfillLabel = uilabel(app.UIFigure);
            app.InfillLabel.Text = 'Infill :';
            app.InfillDropDown = uidropdown(app.UIFigure);
            app.InfillDropDown.Items = {'5', '10', '15'};
            app.InfillDropDown.Value = '10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecimenParamsDialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end